classdef AdminDashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SystemStatusLabel           matlab.ui.control.Label
        ExportDataButton            matlab.ui.control.Button
        MessageLabel                matlab.ui.control.Label
        RefreshButton               matlab.ui.control.Button
        LineSelector                matlab.ui.control.DropDown
        SelectLineLabel             matlab.ui.control.Label
        LogoutButton                matlab.ui.control.Button
        UserManagementButton        matlab.ui.control.Button
        ReportsButton               matlab.ui.control.Button
        ProductionManagementButton  matlab.ui.control.Button
        UsernameLabel               matlab.ui.control.Label
        WellcomeLabel               matlab.ui.control.Label
        FactorysadmindashbordLabel  matlab.ui.control.Label
        ProductionAxes              matlab.ui.control.UIAxes
        EfficiencyAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        productionData               table
    end
    
    methods (Access = public)
        
        % Helper function to update the efficiency chart
        function updateEfficiencyChart(app)
            if isempty(app.productionData)
                app.MessageLabel.Text = 'No production data available to plot.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                return;
            end
        
            selectedLine = app.LineSelector.Value; % Get the selected line name from DropDown
        
            % Filter data for the selected line
            lineData = app.productionData(strcmp(app.productionData.LineName, selectedLine), :);
        
            if isempty(lineData)
                app.MessageLabel.Text = ['No data found for ' selectedLine];
                app.MessageLabel.FontColor = [1 0 0]; % Red
                cla(app.EfficiencyAxes); % Clear the axes if no data
                return;
            end
        
            % Sort data by date for proper plotting
            lineData = sortrows(lineData, 'Date');
        
            % Plotting Efficiency over time
            plot(app.EfficiencyAxes, lineData.Date, lineData.EfficiencyPercentage, '-o', 'LineWidth', 1.5, 'MarkerSize', 5);
            title(app.EfficiencyAxes, ['Efficiency of ' selectedLine]);
            xlabel(app.EfficiencyAxes, 'Date');
            ylabel(app.EfficiencyAxes, 'Efficiency (%)');
            grid(app.EfficiencyAxes, 'on');
        
            app.MessageLabel.Text = ['Chart updated for ' selectedLine];
            app.MessageLabel.FontColor = [0 0.5 0]; % Green
        end
        
        function updateProductionChart(app)
            if isempty(app.productionData)
                app.MessageLabel.Text = 'No production data available for production chart.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                cla(app.ProductionAxes); % Clear axes if no data
                return;
            end
        
            % Aggregate data by ProductType and sum QuantityProduced (as per your Excel)
            % This will show total production for each ProductType across all dates/shifts
            totalProductionByProduct = groupsummary(app.productionData, 'ProductType', 'sum', 'QuantityProduced');
        
            if isempty(totalProductionByProduct)
                app.MessageLabel.Text = 'No production data with ProductType or QuantityProduced found.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                cla(app.ProductionAxes); % Clear axes if no data
                return;
            end
        
            % Clear previous plot on ProductionAxes
            cla(app.ProductionAxes);
        
            % Plotting total production by product type (as a bar chart)
            % Use ProductType for x-axis categories and sum_QuantityProduced for bar heights
            b = bar(app.ProductionAxes, categorical(totalProductionByProduct.ProductType), totalProductionByProduct.sum_QuantityProduced);
            title(app.ProductionAxes, 'Total Production by Product Type');
            xlabel(app.ProductionAxes, 'Product Type');
            ylabel(app.ProductionAxes, 'Total Quantity');
            grid(app.ProductionAxes, 'on');
            app.ProductionAxes.XAxis.TickLabelRotation = 45; % Rotate labels if they overlap
        
            % Optionally, add value labels on top of bars
            text(app.ProductionAxes, b.XEndPoints, b.YEndPoints, string(b.YData), ...
                'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
        
            app.MessageLabel.Text = 'Production chart updated successfully.';
            app.MessageLabel.FontColor = [0 0.5 0]; % Green
        end
        
        function updateSystemStatus(app)
            if isempty(app.productionData)
                app.SystemStatusLabel.Text = 'System Status: No data to evaluate.';
                app.SystemStatusLabel.FontColor = [0.5 0.5 0.5]; % Gray
                return;
            end
        
            % Calculate overall average efficiency
            % Ensure EfficiencyPercentage column exists and is numeric
            if ismember('EfficiencyPercentage', app.productionData.Properties.VariableNames) && isnumeric(app.productionData.EfficiencyPercentage)
                overallAvgEfficiency = mean(app.productionData.EfficiencyPercentage, 'omitnan');
        
                if overallAvgEfficiency < 80 % You can adjust this threshold as needed
                    app.SystemStatusLabel.Text = sprintf('System Status: WARNING! Average Efficiency %.1f%% - Needs Attention!', overallAvgEfficiency);
                    app.SystemStatusLabel.FontColor = [1 0 0]; % Red for warning
                else
                    app.SystemStatusLabel.Text = sprintf('System Status: Normal. Average Efficiency %.1f%%.', overallAvgEfficiency);
                    app.SystemStatusLabel.FontColor = [0 0.5 0]; % Green for normal
                end
            else
                app.SystemStatusLabel.Text = 'System Status: Cannot calculate efficiency (column missing or invalid).';
                app.SystemStatusLabel.FontColor = [0.8 0.5 0]; % Orange for error
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load production data when the app starts
            try
                app.productionData = readtable('ProductionData.xlsx');
            
                % Populate LineSelector DropDown with unique line names
                if ~isempty(app.productionData) && ismember('LineName', app.productionData.Properties.VariableNames)
                    uniqueLines = unique(app.productionData.LineName, 'stable'); % Get unique line names
                    app.LineSelector.Items = uniqueLines; % Set DropDown items
                    app.LineSelector.Value = uniqueLines{1}; % Select the first line by default
                    app.MessageLabel.Text = 'Production data loaded successfully.';
                    app.MessageLabel.FontColor = [0 0.5 0]; % Green
            
                    % Call the plotting function to display initial chart
                    updateEfficiencyChart(app);
                    updateProductionChart(app);
                     updateSystemStatus(app);
                else
                    app.MessageLabel.Text = 'ProductionData.xlsx is empty or missing LineName column.';
                    app.MessageLabel.FontColor = [1 0 0]; % Red
                end
            catch ME
                app.MessageLabel.Text = 'Error loading ProductionData.xlsx.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                disp(['Error loading ProductionData.xlsx: ' ME.message]);
            end
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            % این کد پنجره داشبورد مدیر را می بندد و صفحه ورود را دوباره باز می کند.
            delete(app.UIFigure); % app.UIFigure به پنجره فعلی اشاره می کند
        
            % باز کردن مجدد صفحه ورود
            LoginApp; % این دستور یک نمونه جدید از LoginApp (فایل LoginApp.mlapp) را باز می کند
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % Call the plotting function to update chart based on selection
            updateEfficiencyChart(app);
            updateProductionChart(app);
            updateSystemStatus(app);
        end

        % Button pushed function: ProductionManagementButton
        function ProductionManagementButtonPushed(app, event)
            % Close the current Admin Dashboard window
            delete(app.UIFigure);
            
            % Open the Production Management App
            ProductionManagementApp;
        end

        % Button pushed function: ReportsButton
        function ReportsButtonPushed(app, event)
            % Close the current Admin Dashboard window
            delete(app.UIFigure);
            
            % Open the Reports App
            ReportsApp;
        end

        % Button pushed function: UserManagementButton
        function UserManagementButtonPushed(app, event)
            % Close the current Admin Dashboard window
            delete(app.UIFigure);
            
            % Open the User Management App
            UserManagementApp;
        end

        % Callback function
        function ProductionViewSelectorSelectionChanged(app, event)
            selectedButton = app.ProductionViewSelector.SelectedObject;
            updateProductionChart(app);
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
             if isempty(app.productionData)
                app.MessageLabel.Text = 'No data to export. Load production data first.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                return;
            end
        
            % Get current date and time for filename
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
        
            % Define the filename. You might want to save it in a specific folder.
            % For now, it will save in the current MATLAB working directory.
            filename = ['ProductionData_Export_' timestamp '.xlsx'];
        
            try
                % Write the table to an Excel file
                writetable(app.productionData, filename);
                app.MessageLabel.Text = ['Data exported successfully to ' filename];
                app.MessageLabel.FontColor = [0 0.5 0]; % Green
            catch ME
                app.MessageLabel.Text = 'Error exporting data.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                disp(['Error exporting data: ' ME.message]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EfficiencyAxes = uiaxes(app.UIFigure);
            title(app.EfficiencyAxes, 'EfficiencyAxes')
            xlabel(app.EfficiencyAxes, 'X')
            ylabel(app.EfficiencyAxes, 'Y')
            zlabel(app.EfficiencyAxes, 'Z')
            app.ProductionAxes = uiaxes(app.UIFigure);
            title(app.ProductionAxes, 'ProductionAxes ')
            xlabel(app.ProductionAxes, 'X')
            ylabel(app.ProductionAxes, 'Y')
            zlabel(app.ProductionAxes, 'Z')
            app.FactorysadmindashbordLabel = uilabel(app.UIFigure);
            app.FactorysadmindashbordLabel.Text = 'Factory''s admin dashbord';
            app.WellcomeLabel = uilabel(app.UIFigure);
            app.WellcomeLabel.Text = 'Wellcome';
            app.UsernameLabel = uilabel(app.UIFigure);
            app.UsernameLabel.Text = '';
            app.ProductionManagementButton = uibutton(app.UIFigure, 'push');
            app.ProductionManagementButton.ButtonPushedFcn = createCallbackFcn(app, @ProductionManagementButtonPushed, true);
            app.ProductionManagementButton.Text = 'Production management';
            app.ReportsButton = uibutton(app.UIFigure, 'push');
            app.ReportsButton.ButtonPushedFcn = createCallbackFcn(app, @ReportsButtonPushed, true);
            app.ReportsButton.Text = 'Reports';
            app.UserManagementButton = uibutton(app.UIFigure, 'push');
            app.UserManagementButton.ButtonPushedFcn = createCallbackFcn(app, @UserManagementButtonPushed, true);
            app.UserManagementButton.Text = 'User management';
            app.LogoutButton = uibutton(app.UIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.SelectLineLabel = uilabel(app.UIFigure);
            app.SelectLineLabel.Text = 'Select Line';
            app.LineSelector = uidropdown(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = '';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.SystemStatusLabel = uilabel(app.UIFigure);
            app.SystemStatusLabel.Text = 'System Status: Checking...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdminDashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end