classdef LineStatusViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CloseButton             matlab.ui.control.Button
        ApplyFilterButton       matlab.ui.control.Button
        LineFilterDropDown      matlab.ui.control.DropDown
        SelectLineLabel         matlab.ui.control.Label
        StatusTable             matlab.ui.control.Table
        CurrentLineStatusLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        allLineStatusData table; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define expected columns for LineStatus.xlsx
            expectedLineStatusCols = {'LineID', 'LineName', 'Status', 'CurrentProduct', 'LastUpdateTime'};

            % --- Load LineStatus.xlsx ---
            lineStatusFile = 'LineStatus.xlsx';
            try
                % Initialize app.allLineStatusData with the correct structure
                app.allLineStatusData = table(zeros(0,1), string.empty(0,1), string.empty(0,1), ...
                    string.empty(0,1), NaT(0,1), ...
                    'VariableNames', expectedLineStatusCols);

                tempLineStatusData = readtable(lineStatusFile);

                % Check if all expected columns exist in the loaded table
                if all(ismember(expectedLineStatusCols, tempLineStatusData.Properties.VariableNames))
                    app.allLineStatusData = tempLineStatusData(:, expectedLineStatusCols);
                    % Ensure LastUpdateTime column is datetime type
                    app.allLineStatusData.LastUpdateTime = datetime(app.allLineStatusData.LastUpdateTime);

                    % Populate Line Filter DropDown
                    % Get unique line names, add "All Lines" option, and set default value
                    uniqueLineNames = ["All Lines"; unique(app.allLineStatusData.LineName, 'stable')];
                    app.LineFilterDropDown.Items = uniqueLineNames;
                    app.LineFilterDropDown.Value = "All Lines"; % Select "All Lines" by default

                    % Display all data initially in the table
                    app.StatusTable.Data = app.allLineStatusData;
                else
                    % If columns are missing or malformed in the file
                    app.StatusTable.Data = table('Size', [0, length(expectedLineStatusCols)], ...
                        'VariableNames', expectedLineStatusCols);
                    uialert(app.UIFigure, ['Warning: ' lineStatusFile ' missing or malformed columns. Displaying empty table.'], 'Data Load Warning');
                end
            catch ME
                % Handle file not found or other read errors
                if strcmp(ME.identifier, 'MATLAB:readtable:FileNotFound')
                    uialert(app.UIFigure, ['Error: ' lineStatusFile ' not found. Please run generateLineStatusFile.m to create it.'], 'File Not Found');
                else
                    uialert(app.UIFigure, ['Error loading line status data: ' ME.message], 'Error');
                end
                app.StatusTable.Data = table('Size', [0, length(expectedLineStatusCols)], ...
                    'VariableNames', expectedLineStatusCols);% Define expected columns for LineStatus.xlsx
                % These variable names and types must match the structure of your LineStatus.xlsx
                expectedLineStatusCols = {'LineID', 'LineName', 'Status', 'CurrentProduct', 'LastUpdateTime'};
                expectedLineStatusTypes = {'double', 'string', 'string', 'string', 'datetime'}; % Corresponding data types

                % --- Load LineStatus.xlsx ---
                lineStatusFile = 'LineStatus.xlsx';
                try
                    % Initialize app.allLineStatusData with the correct structure and types
                    % This is used if the file is not found or has issues, to provide an empty but correctly structured table.
                    app.allLineStatusData = table('Size', [0, length(expectedLineStatusCols)], ...
                        'VariableNames', expectedLineStatusCols, ...
                        'VariableTypes', expectedLineStatusTypes);

                    % Attempt to read the LineStatus.xlsx file
                    tempLineStatusData = readtable(lineStatusFile);

                    % Check if all expected columns exist in the loaded table
                    if all(ismember(expectedLineStatusCols, tempLineStatusData.Properties.VariableNames))
                        % If columns are correct, assign the data
                        app.allLineStatusData = tempLineStatusData(:, expectedLineStatusCols);
                        % Ensure LastUpdateTime column is datetime type
                        % This handles cases where Excel might save it as a number or string
                        app.allLineStatusData.LastUpdateTime = datetime(app.allLineStatusData.LastUpdateTime);

                        % Populate Line Filter DropDown with unique line names
                        % Add "All Lines" option at the beginning
                        uniqueLineNames = ["All Lines"; unique(app.allLineStatusData.LineName, 'stable')];
                        app.LineFilterDropDown.Items = uniqueLineNames;
                        app.LineFilterDropDown.Value = "All Lines"; % Select "All Lines" by default

                        % Display all data initially in the table
                        app.StatusTable.Data = app.allLineStatusData;
                    else
                        % If columns are missing or malformed in the file
                        % Display an empty table with correct structure and a warning
                        app.StatusTable.Data = app.allLineStatusData; % Use the pre-initialized empty table
                        uialert(app.UIFigure, ['Warning: ' lineStatusFile ' is missing required columns or has malformed data. Displaying empty table.'], 'Data Load Warning');
                    end
                catch ME
                    % Handle file not found or other read errors
                    if strcmp(ME.identifier, 'MATLAB:readtable:FileNotFound')
                        uialert(app.UIFigure, ['Error: ' lineStatusFile ' not found. Please run createManualLineStatusFile.m to create it.'], 'File Not Found');
                    else
                        uialert(app.UIFigure, ['Error loading line status data: ' ME.message], 'Error');
                    end
                    % Display an empty table with correct structure in case of any error
                    app.StatusTable.Data = app.allLineStatusData; % Use the pre-initialized empty table
                end
            end
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            selectedLine = app.LineFilterDropDown.Value;
            
            if strcmp(selectedLine, "All Lines")
                % If "All Lines" is selected, display all data
                app.StatusTable.Data = app.allLineStatusData;
            else
                % Filter data based on selected line name
                filteredData = app.allLineStatusData(strcmp(app.allLineStatusData.LineName, selectedLine), :);
                app.StatusTable.Data = filteredData;
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.UIFigure); % Close the viewer window
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentLineStatusLabel = uilabel(app.UIFigure);
            app.CurrentLineStatusLabel.Text = 'Current Line Status';
            app.StatusTable = uitable(app.UIFigure);
            app.StatusTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StatusTable.RowName = {};
            app.SelectLineLabel = uilabel(app.UIFigure);
            app.SelectLineLabel.Text = 'Select Line :';
            app.LineFilterDropDown = uidropdown(app.UIFigure);
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LineStatusViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end