classdef LoginApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        MessageLabel   matlab.ui.control.Label
        LoginButton    matlab.ui.control.Button
        PasswordField  matlab.ui.control.EditField
        UsernameField  matlab.ui.control.EditField
        paswordLabel   matlab.ui.control.Label
        usernameLabel  matlab.ui.control.Label
        factorymonitoringsystemloginLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
           % Clear previous messages and reset text color
            app.MessageLabel.Text = ''; 
            app.MessageLabel.FontColor = [0 0 0]; % Reset to black for normal text
        
            % Read username and password from the input fields
            inputUsername = app.UsernameField.Value;
            inputPassword = app.PasswordField.Value;
        
            % --- Load User Data from Users.xlsx ---
            % Ensure Users.xlsx is in the same directory as LoginApp.mlapp.
            try
                userData = readtable('Users.xlsx');
            catch ME
                app.MessageLabel.Text = 'Error loading user data.';
                app.MessageLabel.FontColor = [1 0 0]; % Red color for error
                disp(['Error loading Users.xlsx: ' ME.message]); % Display error in Command Window for debugging
                return; % Exit if the file cannot be loaded
            end
        
            % --- Authentication Logic ---
            loggedInUserRow = []; % Variable to store the row number of the found user
            
            % Loop through each row in the user data table to find a match
            for i = 1:height(userData)
                % Remove any leading/trailing spaces from stored username and password from Excel
                storedUsername = strtrim(userData.Username{i});
                storedPassword = strtrim(userData.Password{i});
                
                % Compare the entered username and password with the data from Excel
                % Also trim spaces from user input for robust comparison.
                if strcmp(storedUsername, strtrim(inputUsername)) && strcmp(storedPassword, strtrim(inputPassword))
                    loggedInUserRow = i; % If a match is found, store the row number
                    break; % User found, exit the loop
                end
            end
        
            % --- Check Login Result ---
            if ~isempty(loggedInUserRow)
                % Successful login
                userRole = strtrim(userData.Role{loggedInUserRow}); % Get the user's role from the table
                app.MessageLabel.Text = [inputUsername];
                app.MessageLabel.FontColor = [0 0.5 0]; % Green color for success message
                
                % --- Open the appropriate dashboard based on the user's role ---
                if strcmp(userRole, 'admin')
                    app.MessageLabel.Text = 'Login successful! Redirecting to Admin Dashboard...';
                    
                    try
                        % Open the Admin Dashboard App
                        % Instantiating the app class automatically displays its UI figure.
                        adminApp = AdminDashboard; 
                        % Set the UsernameLabel text in the Admin Dashboard
                        adminApp.UsernameLabel.Text = [inputUsername]; 
                        
                        delete(app.UIFigure); % Close the login window
                    catch ME
                        app.MessageLabel.Text = 'Error opening Admin Dashboard. Please check the file.';
                        app.MessageLabel.FontColor = [1 0 0];
                        disp(['Error opening Admin Dashboard: ' ME.message]);
                    end
                    
                elseif strcmp(userRole, 'operator')
                    app.MessageLabel.Text = 'Login successful! Redirecting to Operator Dashboard...';
                    
                    try
                        % Open the Operator Dashboard App
                        % Instantiating the app class automatically displays its UI figure.
                        operatorApp = OperatorDashboard; 
                        % Set the UsernameLabel text in the Operator Dashboard
                        operatorApp.UsernameLabel.Text = [inputUsername]; 
                        
                        delete(app.UIFigure); % Close the login window
                    catch ME
                        app.MessageLabel.Text = 'Error opening Operator Dashboard. Please check the file.';
                        app.MessageLabel.FontColor = [1 0 0];
                        disp(['Error opening Operator Dashboard: ' ME.message]);
                    end
                end
            else
                % Login failed
                app.MessageLabel.Text = 'Login failed. Invalid username or password.';
                app.MessageLabel.FontColor = [1 0 0]; % Red color for error
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.factorymonitoringsystemloginLabel = uilabel(app.UIFigure);
            app.factorymonitoringsystemloginLabel.Interpreter = 'tex';
            app.factorymonitoringsystemloginLabel.Text = 'factory monitoring system login';
            app.usernameLabel = uilabel(app.UIFigure);
            app.usernameLabel.Text = 'username : ';
            app.paswordLabel = uilabel(app.UIFigure);
            app.paswordLabel.Text = 'pasword : ';
            app.UsernameField = uieditfield(app.UIFigure, 'text');
            app.PasswordField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end