classdef OperatorDashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LogoutButton                   matlab.ui.control.Button
        ViewLineStatusButton           matlab.ui.control.Button
        ReportFaultButton              matlab.ui.control.Button
        RecordProductionButton         matlab.ui.control.Button
        UsernameLabel                  matlab.ui.control.Label
        WellcomeLabel                  matlab.ui.control.Label
        FactorysoperatordashbordLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            % This code closes the current operator dashboard window
            delete(app.UIFigure); % app.UIFigure refers to the current app window
            % Open the login page again
            LoginApp; % This command opens a new instance of LoginApp (the LoginApp.mlapp file)
        end

        % Button pushed function: RecordProductionButton
        function RecordProductionButtonPushed(app, event)
            delete(app.UIFigure);
            % Open the RecordProductionForm app
            RecordProductionForm;
        end

        % Button pushed function: ViewLineStatusButton
        function ViewLineStatusButtonPushed(app, event)
            delete(app.UIFigure);
            LineStatusViewer;
        end

        % Button pushed function: ReportFaultButton
        function ReportFaultButtonPushed(app, event)
            delete(app.UIFigure);
            ReportFaultForm;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FactorysoperatordashbordLabel = uilabel(app.UIFigure);
            app.FactorysoperatordashbordLabel.Text = 'Factory''s operator dashbord';
            app.WellcomeLabel = uilabel(app.UIFigure);
            app.WellcomeLabel.Text = 'Wellcome';
            app.UsernameLabel = uilabel(app.UIFigure);
            app.UsernameLabel.Text = '';
            app.RecordProductionButton = uibutton(app.UIFigure, 'push');
            app.RecordProductionButton.ButtonPushedFcn = createCallbackFcn(app, @RecordProductionButtonPushed, true);
            app.RecordProductionButton.Text = 'Record Production';
            app.ReportFaultButton = uibutton(app.UIFigure, 'push');
            app.ReportFaultButton.ButtonPushedFcn = createCallbackFcn(app, @ReportFaultButtonPushed, true);
            app.ReportFaultButton.Text = 'ReportFaultButton ';
            app.ViewLineStatusButton = uibutton(app.UIFigure, 'push');
            app.ViewLineStatusButton.ButtonPushedFcn = createCallbackFcn(app, @ViewLineStatusButtonPushed, true);
            app.ViewLineStatusButton.Text = 'ViewLineStatus';
            app.LogoutButton = uibutton(app.UIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OperatorDashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end