classdef ProductionManagementApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ShowColumnsButton               matlab.ui.control.Button
        MessageLabel                    matlab.ui.control.Label
        ProductionTable                 matlab.ui.control.Table
        DeleteProductionButton          matlab.ui.control.Button
        UpdateProductionButton          matlab.ui.control.Button
        AddProductionButton             matlab.ui.control.Button
        ProductionDetailsPanel          matlab.ui.container.Panel
        QuantityProducedField           matlab.ui.control.NumericEditField
        QuantityProducedEditFieldLabel  matlab.ui.control.Label
        ProductTypeDropDown             matlab.ui.control.DropDown
        ProductTypeDropDownLabel        matlab.ui.control.Label
        ShiftDropDown                   matlab.ui.control.DropDown
        shiftDropDownLabel              matlab.ui.control.Label
        DateField                       matlab.ui.control.DatePicker
        DateLabel                       matlab.ui.control.Label
        LineNameDropDown                matlab.ui.control.DropDown
        LineNameDropDownLabel           matlab.ui.control.Label
        LineIDField                     matlab.ui.control.NumericEditField
        LineIDEditFieldLabel            matlab.ui.control.Label
        ProductionManagementLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        productionData table;
        selectedProductionID double;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define the expected column names, including ProductionID now
            expectedCols = {'ProductionID', 'LineID', 'LineName', 'Date', 'Shift', 'ProductType', 'QuantityProduced', 'EfficiencyPercentage'};
            
            try
                % Attempt to read the ProductionData.xlsx file
                tempProductionData = readtable('ProductionData.xlsx');
        
                % Check if all expected columns exist in the loaded table.
                % This also helps in case the file was manually edited and columns got messed up.
                if all(ismember(expectedCols, tempProductionData.Properties.VariableNames))
                    % If columns exist, reorder them to match expectedCols and assign
                    app.productionData = tempProductionData(:, expectedCols);
                    app.MessageLabel.Text = 'Production data loaded successfully.';
                    app.MessageLabel.FontColor = [0 0.5 0]; % Green
                else
                    % If some expected columns are missing or incorrectly named,
                    % initialize an empty table with the correct column structure.
                    app.MessageLabel.Text = 'Warning: ProductionData.xlsx missing or malformed columns. Initializing empty production data.';
                    app.MessageLabel.FontColor = [0.8 0.5 0]; % Orange
                    
                    % Initialize with correct types for all columns, including ProductionID
                    app.productionData = table(zeros(0,1), zeros(0,1), string.empty(0,1), datetime.empty(0,1), ...
                                               zeros(0,1), string.empty(0,1), zeros(0,1), zeros(0,1), ...
                                               'VariableNames', expectedCols);
                end
                
                % Set table column names
                app.ProductionTable.ColumnName = expectedCols;
                % Display the loaded or initialized table
                app.ProductionTable.Data = app.productionData;
                
            catch ME
                % This catch block handles file not found or other readtable errors
                if (strcmp(ME.identifier, 'MATLAB:readtable:FileNotFound') || ...
                    strcmp(ME.identifier, 'MATLAB:table:EmptyTable') || ...
                    contains(ME.message, 'Variable names must be valid MATLAB identifiers')) % Added check for invalid variable names
                    
                    app.MessageLabel.Text = 'No production file found or file is empty/corrupted. A new one will be created upon saving.';
                    app.MessageLabel.FontColor = [0.5 0.5 0.5]; % Gray
                else
                    % Catch any other unexpected errors during file loading
                    app.MessageLabel.Text = ['Error loading production data: ' ME.message];
                    app.MessageLabel.FontColor = [1 0 0]; % Red
                end
                
                % IMPORTANT: Always ensure app.productionData is initialized with the correct structure
                % even if an error occurs, so subsequent functions don't fail.
                % Initialize with correct types for all columns, including ProductionID
                app.productionData = table(zeros(0,1), zeros(0,1), string.empty(0,1), datetime.empty(0,1), ...
                                           zeros(0,1), string.empty(0,1), zeros(0,1), zeros(0,1), ...
                                           'VariableNames', expectedCols);
                % Set table column names
                app.ProductionTable.ColumnName = expectedCols;
                % Display the (potentially empty) table
                app.ProductionTable.Data = app.productionData;
            end
        end

        % Button pushed function: ShowColumnsButton
        function ShowColumnsButtonPushed(app, event)
            if ~isempty(app.productionData) && istable(app.productionData)
                disp('Column Names in app.productionData:');
                disp(app.productionData.Properties.VariableNames);
                app.MessageLabel.Text = 'Column names displayed in Command Window.';
                app.MessageLabel.FontColor = [0 0.5 0]; % Green
            else
                app.MessageLabel.Text = 'No production data loaded to display column names.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
            end
        end

        % Button pushed function: AddProductionButton
        function AddProductionButtonPushed(app, event)
            % 1. Get values from input fields and explicitly convert to string where needed
                lineID = app.LineIDField.Value;
                lineName = string(app.LineNameDropDown.Value); % <-- Changed: Explicitly convert to string
                date = app.DateField.Value;
                shift = str2double(app.ShiftDropDown.Value); 
                productType = string(app.ProductTypeDropDown.Value); % <-- Changed: Explicitly convert to string
                quantityProduced = app.QuantityProducedField.Value;
                efficiencyPercentage = 0; % Default for now, will be calculated later
    
                % 2. Basic Input Validation
                if isnan(lineID) || ~isnumeric(lineID) || lineID <= 0
                    app.MessageLabel.Text = 'Line ID must be a positive number.';
                    app.MessageLabel.FontColor = [1 0 0]; 
                    return;
                end
                if isnan(quantityProduced) || ~isnumeric(quantityProduced) || quantityProduced <= 0
                    app.MessageLabel.Text = 'Quantity Produced must be a positive number.';
                    app.MessageLabel.FontColor = [1 0 0]; 
                    return;
                end
                
                % 3. Generate a unique ProductionID
                if isempty(app.productionData)
                    newProductionID = 1; 
                else
                    newProductionID = max(app.productionData.ProductionID) + 1;
                end
                
                % 4. Create a new row as a table
                % Ensure all data types match the table's expected types.
                % The string() conversion above should fix the issue.
                newRow = table(newProductionID, lineID, lineName, date, shift, productType, quantityProduced, efficiencyPercentage, ...
                               'VariableNames', {'ProductionID', 'LineID', 'LineName', 'Date', 'Shift', 'ProductType', 'QuantityProduced', 'EfficiencyPercentage'});
                
                % 5. Append the new row to the productionData property
                app.productionData = [app.productionData; newRow];
                
                % 6. Update the ProductionTable display
                app.ProductionTable.Data = app.productionData;
                
                % 7. Write the updated data back to the Excel file
                try
                    writetable(app.productionData, 'ProductionData.xlsx', 'WriteVariableNames', true);
                    app.MessageLabel.Text = 'Production record added and saved successfully.';
                    app.MessageLabel.FontColor = [0 0.5 0]; 
                catch ME
                    app.MessageLabel.Text = ['Error saving data to Excel: ' ME.message];
                    app.MessageLabel.FontColor = [1 0 0]; 
                end
                
                % 8. Clear input fields for next entry
                app.LineIDField.Value = 0; 
                app.LineNameDropDown.Value = 'Refrigerator Assembly'; 
                app.DateField.Value = datetime('now'); 
                app.ShiftDropDown.Value = '1'; 
                app.ProductTypeDropDown.Value = 'Refrigerator'; 
                app.QuantityProducedField.Value = 0;
        end

        % Callback function: ProductionTable
        function ProductionTableSelectionChanged(app, event)
            % Get the indices of the selected rows
            selectedRowsIdx = app.ProductionTable.Selection;

            % Ensure a row is actually selected (and not multiple or none)
            if ~isempty(selectedRowsIdx)
                % If multiple rows are selected, just take the first one for populating fields
                selectedRowIdx = selectedRowsIdx(1);

                % Get the data of the selected row
                selectedProductionData = app.productionData(selectedRowIdx, :);

                % Populate the input fields with the selected row's data
                app.LineIDField.Value = selectedProductionData.LineID;
                app.LineNameDropDown.Value = selectedProductionData.LineName;
                app.DateField.Value = selectedProductionData.Date;
                % Convert numeric Shift back to string for DropDown display
                app.ShiftDropDown.Value = num2str(selectedProductionData.Shift); 
                app.ProductTypeDropDown.Value = selectedProductionData.ProductType;
                app.QuantityProducedField.Value = selectedProductionData.QuantityProduced;
                % Note: We don't populate efficiencyPercentage as it's not inputtable by user

                % Store the ProductionID of the selected row. This is crucial for updating later.
                % We'll add a new property for this.
                app.selectedProductionID = selectedProductionData.ProductionID; 

                app.MessageLabel.Text = 'Selected row loaded for editing.';
                app.MessageLabel.FontColor = [0 0 0.8]; % Blue
            else
                % No row selected, clear input fields and selected ID
                app.LineIDField.Value = 0; 
                app.LineNameDropDown.Value = 'Refrigerator Assembly'; 
                app.DateField.Value = datetime('now'); 
                app.ShiftDropDown.Value = '1'; 
                app.ProductTypeDropDown.Value = 'Refrigerator'; 
                app.QuantityProducedField.Value = 0; 
                app.selectedProductionID = []; % Clear the stored ID
                app.MessageLabel.Text = 'No row selected.';
                app.MessageLabel.FontColor = [0.5 0.5 0.5];
            end
        end

        % Button pushed function: UpdateProductionButton
        function UpdateProductionButtonPushed(app, event)
            % 1. Check if a row is selected for update
            if isempty(app.selectedProductionID)
                app.MessageLabel.Text = 'Please select a row from the table to update.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                return;
            end
            
            % 2. Get updated values from input fields
            updatedLineID = app.LineIDField.Value;
            updatedLineName = string(app.LineNameDropDown.Value); % Already converted to string scalar
            updatedDate = app.DateField.Value;
            updatedShift = str2double(app.ShiftDropDown.Value); % Convert string to double
            updatedProductType = string(app.ProductTypeDropDown.Value); % Already converted to string scalar
            updatedQuantityProduced = app.QuantityProducedField.Value;
            % EfficiencyPercentage is not updated directly by user, so we keep existing or recalculate later
            
            % 3. Basic Input Validation (similar to Add)
            if isnan(updatedLineID) || ~isnumeric(updatedLineID) || updatedLineID <= 0
                app.MessageLabel.Text = 'Line ID must be a positive number.';
                app.MessageLabel.FontColor = [1 0 0];
                return;
            end
            if isnan(updatedQuantityProduced) || ~isnumeric(updatedQuantityProduced) || updatedQuantityProduced <= 0
                app.MessageLabel.Text = 'Quantity Produced must be a positive number.';
                app.MessageLabel.FontColor = [1 0 0];
                return;
            end
            
            % 4. Find the index of the row to update using selectedProductionID
            rowIndexToUpdate = find(app.productionData.ProductionID == app.selectedProductionID);
            
            if isempty(rowIndexToUpdate)
                app.MessageLabel.Text = 'Error: Selected production record not found in data.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                app.selectedProductionID = []; % Clear selected ID as it might be stale
                return;
            end
            
            % If multiple rows somehow have the same ID (shouldn't happen with unique ID generation),
            % just update the first one found.
            rowIndexToUpdate = rowIndexToUpdate(1); 
            
            % 5. Update the specific row in app.productionData
            app.productionData.LineID(rowIndexToUpdate) = updatedLineID;
            % Convert string scalar to 1x1 cell array before assignment for LineName
            app.productionData.LineName(rowIndexToUpdate) = {updatedLineName}; % <-- تغییر اینجا
            app.productionData.Date(rowIndexToUpdate) = updatedDate;
            app.productionData.Shift(rowIndexToUpdate) = updatedShift;
            % Convert string scalar to 1x1 cell array before assignment for ProductType
            app.productionData.ProductType(rowIndexToUpdate) = {updatedProductType}; % <-- تغییر اینجا
            app.productionData.QuantityProduced(rowIndexToUpdate) = updatedQuantityProduced;
            % EfficiencyPercentage is not updated here, it remains as is or calculated elsewhere.
            
            % 6. Update the ProductionTable display
            app.ProductionTable.Data = app.productionData;
            
            % 7. Write the updated data back to the Excel file
            try
                writetable(app.productionData, 'ProductionData.xlsx', 'WriteVariableNames', true);
                app.MessageLabel.Text = 'Production record updated and saved successfully.';
                app.MessageLabel.FontColor = [0 0.5 0]; % Green
            catch ME
                app.MessageLabel.Text = ['Error saving updated data to Excel: ' ME.message];
                app.MessageLabel.FontColor = [1 0 0]; % Red
            end
            
            % 8. Clear input fields and selected ID after update
            app.LineIDField.Value = 0; 
            app.LineNameDropDown.Value = 'Refrigerator Assembly'; 
            app.DateField.Value = datetime('now'); 
            app.ShiftDropDown.Value = '1'; 
            app.ProductTypeDropDown.Value = 'Refrigerator'; 
            app.QuantityProducedField.Value = 0; 
            app.selectedProductionID = []; % Crucial: Clear selected ID after update
        end

        % Button pushed function: DeleteProductionButton
        function DeleteProductionButtonPushed(app, event)
             % 1. Check if a row is selected for deletion
            if isempty(app.selectedProductionID)
                app.MessageLabel.Text = 'Please select a row from the table to delete.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                return;
            end

            % 2. (Optional but Recommended) Ask for user confirmation
            selection = uiconfirm(app.UIFigure, ...
                                  ['Are you sure you want to delete Production ID ' num2str(app.selectedProductionID) '?'], ...
                                  'Confirm Deletion', ...
                                  'Icon', 'warning', ...
                                  'Options', {'Yes','No'});

            if strcmp(selection, 'No')
                app.MessageLabel.Text = 'Deletion cancelled.';
                app.MessageLabel.FontColor = [0.5 0.5 0.5]; % Gray
                return; % User cancelled deletion
            end

            % 3. Find the index of the row to delete using selectedProductionID
            rowIndexToDelete = find(app.productionData.ProductionID == app.selectedProductionID);

            if isempty(rowIndexToDelete)
                app.MessageLabel.Text = 'Error: Selected production record not found in data for deletion.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                % Clear selected ID as it might be stale
                app.selectedProductionID = []; 
                return;
            end

            % If multiple rows somehow have the same ID (shouldn't happen with unique ID generation),
            % just delete the first one found.
            rowIndexToDelete = rowIndexToDelete(1); 

            % 4. Remove the row from app.productionData
            app.productionData(rowIndexToDelete, :) = []; % Removes the selected row

            % 5. Update the ProductionTable display
            app.ProductionTable.Data = app.productionData;

            % 6. Write the updated data back to the Excel file
            try
                writetable(app.productionData, 'ProductionData.xlsx', 'WriteVariableNames', true);
                app.MessageLabel.Text = 'Production record deleted and saved successfully.';
                app.MessageLabel.FontColor = [0 0.5 0]; % Green
            catch ME
                app.MessageLabel.Text = ['Error saving data after deletion to Excel: ' ME.message];
                app.MessageLabel.FontColor = [1 0 0]; % Red
            end

            % 7. Clear input fields and selected ID after deletion
            app.LineIDField.Value = 0; 
            app.LineNameDropDown.Value = 'Refrigerator Assembly'; 
            app.DateField.Value = datetime('now'); 
            app.ShiftDropDown.Value = '1'; 
            app.ProductTypeDropDown.Value = 'Refrigerator'; 
            app.QuantityProducedField.Value = 0; 
            app.selectedProductionID = []; % Crucial: Clear selected ID after deletion
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProductionManagementLabel = uilabel(app.UIFigure);
            app.ProductionManagementLabel.Text = 'Production Management';
            app.ProductionDetailsPanel = uipanel(app.UIFigure);
            app.ProductionDetailsPanel.Title = 'Production Details';
            app.LineIDEditFieldLabel = uilabel(app.ProductionDetailsPanel);
            app.LineIDEditFieldLabel.Text = 'Line ID :';
            app.LineIDField = uieditfield(app.ProductionDetailsPanel, 'numeric');
            app.LineNameDropDownLabel = uilabel(app.ProductionDetailsPanel);
            app.LineNameDropDownLabel.Text = 'Line Name :';
            app.LineNameDropDown = uidropdown(app.ProductionDetailsPanel);
            app.LineNameDropDown.Items = {'Refrigerator Assembly', 'Washing Machine Assembly', 'Plastic Parts Production', 'Washing Machine Quality Control', 'Logistics and Refrigerator Quality Control', 'Packaging Line', 'Electronic Parts Production'};
            app.LineNameDropDown.Value = 'Refrigerator Assembly';
            app.DateLabel = uilabel(app.ProductionDetailsPanel);
            app.DateLabel.Text = 'Date';
            app.DateField = uidatepicker(app.ProductionDetailsPanel);
            app.shiftDropDownLabel = uilabel(app.ProductionDetailsPanel);
            app.shiftDropDownLabel.Text = 'shift';
            app.ShiftDropDown = uidropdown(app.ProductionDetailsPanel);
            app.ShiftDropDown.Items = {'1', '2', '3'};
            app.ShiftDropDown.Value = '1';
            app.ProductTypeDropDownLabel = uilabel(app.ProductionDetailsPanel);
            app.ProductTypeDropDownLabel.Text = 'Product Type';
            app.ProductTypeDropDown = uidropdown(app.ProductionDetailsPanel);
            app.ProductTypeDropDown.Items = {'Washing Machine', 'Refrigerator', 'Other Parts'};
            app.ProductTypeDropDown.Value = 'Washing Machine';
            app.QuantityProducedEditFieldLabel = uilabel(app.ProductionDetailsPanel);
            app.QuantityProducedEditFieldLabel.Text = 'Quantity Produced';
            app.QuantityProducedField = uieditfield(app.ProductionDetailsPanel, 'numeric');
            app.AddProductionButton = uibutton(app.UIFigure, 'push');
            app.AddProductionButton.ButtonPushedFcn = createCallbackFcn(app, @AddProductionButtonPushed, true);
            app.AddProductionButton.Text = 'Add Production';
            app.UpdateProductionButton = uibutton(app.UIFigure, 'push');
            app.UpdateProductionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateProductionButtonPushed, true);
            app.UpdateProductionButton.Text = 'Update Production';
            app.DeleteProductionButton = uibutton(app.UIFigure, 'push');
            app.DeleteProductionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProductionButtonPushed, true);
            app.DeleteProductionButton.Text = 'Delete Production';
            app.ProductionTable = uitable(app.UIFigure);
            app.ProductionTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ProductionTable.RowName = {};
            app.ProductionTable.SelectionChangedFcn = createCallbackFcn(app, @ProductionTableSelectionChanged, true);
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Messages :';
            app.ShowColumnsButton = uibutton(app.UIFigure, 'push');
            app.ShowColumnsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowColumnsButtonPushed, true);
            app.ShowColumnsButton.Text = 'Show Columns';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProductionManagementApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end