classdef RecordProductionForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MessageLabel              matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        QuantityField             matlab.ui.control.NumericEditField
        QuantityProducedLabel     matlab.ui.control.Label
        ProductDropDown           matlab.ui.control.DropDown
        ProductLabel              matlab.ui.control.Label
        LineDropDown              matlab.ui.control.DropDown
        LineLabel                 matlab.ui.control.Label
        DateField                 matlab.ui.control.DatePicker
        DateLabel                 matlab.ui.control.Label
        RecordNewProductionLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        productionData table; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Define expected columns for ProductionData.xlsx
            expectedProductionCols = {'ProductionID', 'Date', 'LineName', 'ProductType', 'QuantityProduced'};
    
            % --- Load ProductionData.xlsx (robustly, using the method we perfected) ---
            try
                % Always initialize productionData with the correct structure first
                % This ensures app.productionData always has the correct columns, even if the file is problematic
                app.productionData = table(zeros(0,1), NaT(0,1), string.empty(0,1), ...
                                           string.empty(0,1), zeros(0,1), ...
                                           'VariableNames', expectedProductionCols);
    
                tempProductionData = readtable('ProductionData.xlsx');
    
                % Check if all expected columns exist in the loaded table from the file.
                if all(ismember(expectedProductionCols, tempProductionData.Properties.VariableNames))
                    app.productionData = tempProductionData(:, expectedProductionCols);
                    % Ensure Date column is datetime type for consistency
                    app.productionData.Date = datetime(app.productionData.Date);
                    app.MessageLabel.Text = 'Production data loaded successfully.';
                    app.MessageLabel.FontColor = [0 0.5 0]; % Green
                else
                    app.MessageLabel.Text = 'Warning: ProductionData.xlsx missing or malformed columns. Initializing empty production data.';
                    app.MessageLabel.FontColor = [0.8 0.5 0]; % Orange
                    % app.productionData is already correctly initialized at the start, no need to re-initialize here.
                end
            catch ME
                if (strcmp(ME.identifier, 'MATLAB:readtable:FileNotFound') || ...
                    strcmp(ME.identifier, 'MATLAB:table:EmptyTable') || ...
                    strcmp(ME.identifier, 'MATLAB:readtable:FileFormatError'))
                    app.MessageLabel.Text = 'No production file found or file is empty. A new one will be created upon saving.';
                    app.MessageLabel.FontColor = [0.5 0.5 0.5]; % Gray
                else
                    app.MessageLabel.Text = ['Error loading production data: ' ME.message];
                    app.MessageLabel.FontColor = [1 0 0]; % Red
                end
                % app.productionData is already correctly initialized at the start, no need to re-initialize here.
            end
    
            % --- Populate Line DropDown from a predefined list ---
            lineNames = {'Refrigerator Assembly', 'Plastic Parts Production', 'Washing Machine Assembly', 'Refrigerator Quality Control', 'Washing Machine Quality Control', 'Electronic Parts Production', 'Packaging Line', 'Logistics and Warehousing'}; 
            app.LineDropDown.Items = lineNames;
            if ~isempty(lineNames)
                app.LineDropDown.Value = lineNames{1}; % Select the first item by default
            end
    
            % --- Populate Product DropDown from a predefined list ---
            productNames = {'Washing Machine', 'Refrigerator', 'Other Parts'}; 
            app.ProductDropDown.Items = productNames;
            if ~isempty(productNames)
                app.ProductDropDown.Value = productNames{1}; % Select the first item by default
            end
    
            % Set default date to today
            app.DateField.Value = datetime('today');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             % Get input values from UI components
            inputDate = app.DateField.Value;
            inputLine = app.LineDropDown.Value;
            inputProduct = app.ProductDropDown.Value;
            inputQuantity = app.QuantityField.Value;
    
            % --- Input validation ---
            if isempty(inputDate) || isempty(inputLine) || isempty(inputProduct) || isempty(inputQuantity) || inputQuantity <= 0
                app.MessageLabel.Text = 'All fields must be filled and Quantity must be a positive number.';
                app.MessageLabel.FontColor = [1 0 0]; % Red color for error message
                return; % Stop function execution
            end
    
            % --- Generate a new ProductionID ---
            if isempty(app.productionData) || all(isnan(app.productionData.ProductionID))
                newProductionID = 1; % Start with 1 if table is empty or IDs are NaN
            else
                newProductionID = max(app.productionData.ProductionID) + 1; % Increment from max existing ID
            end
    
            % --- Create a new row for the table ---
            % Ensure the order matches expectedProductionCols: ProductionID, Date, LineName, ProductName, QuantityProduced
            newRow = {newProductionID, inputDate, inputLine, inputProduct, inputQuantity};
    
            % --- Append the new row to the productionData table ---
            app.productionData = [app.productionData; newRow];
    
            try
                % --- Save the updated table back to ProductionData.xlsx ---
                % 'WriteMode', 'overwrite' ensures the entire file is rewritten with updated data
                writetable(app.productionData, 'ProductionData.xlsx', 'WriteMode', 'overwrite');
                app.MessageLabel.Text = 'Production record saved successfully!';
                app.MessageLabel.FontColor = [0 0.5 0]; % Green color for success message
    
                % --- Clear inputs for the next entry (optional, but good UX) ---
                app.QuantityField.Value = 0; % Reset quantity to default
                app.DateField.Value = datetime('today'); % Reset date to today
                app.LineDropDown.Value = app.LineDropDown.Items{1}; % Reset to first line
                app.ProductDropDown.Value = app.ProductDropDown.Items{1}; % Reset to first product
            catch ME
                % Handle any errors during file saving
                app.MessageLabel.Text = ['Error saving record: ' ME.message];
                app.MessageLabel.FontColor = [1 0 0]; % Red color for error
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Close the form without saving any new data
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RecordNewProductionLabel = uilabel(app.UIFigure);
            app.RecordNewProductionLabel.Text = 'Record New Production';
            app.DateLabel = uilabel(app.UIFigure);
            app.DateLabel.Text = 'Date :';
            app.DateField = uidatepicker(app.UIFigure);
            app.LineLabel = uilabel(app.UIFigure);
            app.LineLabel.Text = 'LineID :';
            app.LineDropDown = uidropdown(app.UIFigure);
            app.LineDropDown.Items = {'1', '2', '3'};
            app.LineDropDown.Value = '1';
            app.ProductLabel = uilabel(app.UIFigure);
            app.ProductLabel.Text = 'Product :';
            app.ProductDropDown = uidropdown(app.UIFigure);
            app.QuantityProducedLabel = uilabel(app.UIFigure);
            app.QuantityProducedLabel.Text = 'Quantity Produced:';
            app.QuantityField = uieditfield(app.UIFigure, 'numeric');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save Record';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancle';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Messages :';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecordProductionForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end