classdef ReportFaultForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CancelButton          matlab.ui.control.Button
        SubmitButton          matlab.ui.control.Button
        DateDatePicker        matlab.ui.control.DatePicker
        DateDatePickerLabel   matlab.ui.control.Label
        DescriptionTextArea   matlab.ui.control.TextArea
        DescriptionLabel      matlab.ui.control.Label
        FaultTypeDropDown     matlab.ui.control.DropDown
        FaultTypeLabel        matlab.ui.control.Label
        ReportaNewFaultLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        faultsData table;
        faultsFile = 'Faults.xlsx';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              % Define expected columns and types for INTERNAL app.faultsData.
            % 'Date' will be datetime internally.
            expectedFaultCols = {'FaultID', 'Date', 'FaultType', 'Description'};
            expectedFaultTypes = {'double', 'datetime', 'string', 'string'}; 

            % Initialize app.faultsData with the correct internal structure.
            app.faultsData = table('Size', [0, length(expectedFaultCols)], ...
                                   'VariableNames', expectedFaultCols, ...
                                   'VariableTypes', expectedFaultTypes);

            % Try to load existing faults data from Excel
            if exist(app.faultsFile, 'file') == 2
                try
                    % Read the Excel file. We expect readtable to correctly parse 'Date' as datetime.
                    tempExcelData = readtable(app.faultsFile, 'ReadVariableNames', true);

                    % Check if all expected columns exist.
                    if all(ismember(expectedFaultCols, tempExcelData.Properties.VariableNames))
                        % Assign loaded data. We assume tempExcelData.Date is already datetime.
                        app.faultsData = tempExcelData(:, expectedFaultCols);
                        % As a fallback/safety, if it's not datetime, try to convert (optional, but good practice if issues persist)
                        if ~isdatetime(app.faultsData.Date)
                            app.faultsData.Date = datetime(app.faultsData.Date); % Try default conversion
                        end
                    else
                        uialert(app.UIFigure, ['Warning: ' app.faultsFile ' has missing or malformed columns. Starting with an empty fault log.'], 'Data Load Warning', 'Icon', 'warning');
                    end
                catch ME
                    uialert(app.UIFigure, ['Error loading existing fault data: ' ME.message], 'Error', 'Icon', 'error');
                end
            else
                uialert(app.UIFigure, ['Note: ' app.faultsFile ' not found. A new file will be created upon first submission.'], 'File Not Found', 'Icon', 'info');
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            % Get data from UI components
            faultType = app.FaultTypeDropDown.Value;
            description = app.DescriptionTextArea.Value;
            faultDate = app.DatePicker.Value; % This is already a datetime object.

            % Basic validation: Ensure description is not empty or just whitespace.
            if isempty(strtrim(description))
                uialert(app.UIFigure, 'Please provide a description for the fault.', 'Input Error', 'Icon', 'error');
                return;
            end

            % Generate a unique FaultID.
            if isempty(app.faultsData)
                newFaultID = 1;
            else
                newFaultID = max(app.faultsData.FaultID) + 1;
            end

            % Create a new table row for internal storage (Date is datetime here).
            newRowTable = table(newFaultID, faultDate, string(faultType), string(description), ...
                               'VariableNames', {'FaultID', 'Date', 'FaultType', 'Description'});

            % Append the new row to the internal app.faultsData table.
            app.faultsData = [app.faultsData; newRowTable];

            % Save the updated table to Excel.
            try
                % Write the app.faultsData directly to Excel.
                % We expect writetable to handle datetime correctly.
                writetable(app.faultsData, app.faultsFile, 'WriteMode', 'overwrite'); 
                uialert(app.UIFigure, 'Fault reported successfully!', 'Success', 'Icon', 'success');

                % Optionally, clear form fields after successful submission.
                app.FaultTypeDropDown.Value = app.FaultTypeDropDown.Items{1}; % Reset to first item
                app.DescriptionTextArea.Value = '';
                app.DatePicker.Value = datetime('today'); % Reset date to today

            catch ME
                uialert(app.UIFigure, ['Error saving fault data: ' ME.message], 'Save Error', 'Icon', 'error');
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReportaNewFaultLabel = uilabel(app.UIFigure);
            app.ReportaNewFaultLabel.Text = 'Report a New Fault';
            app.FaultTypeLabel = uilabel(app.UIFigure);
            app.FaultTypeLabel.Text = 'Fault Type :';
            app.FaultTypeDropDown = uidropdown(app.UIFigure);
            app.FaultTypeDropDown.Items = {'Mechanical', 'Electrical', 'Software', 'Operational', 'Other'};
            app.FaultTypeDropDown.Value = 'Mechanical';
            app.DescriptionLabel = uilabel(app.UIFigure);
            app.DescriptionLabel.Text = 'Description :';
            app.DescriptionTextArea = uitextarea(app.UIFigure);
            app.DateDatePickerLabel = uilabel(app.UIFigure);
            app.DateDatePickerLabel.Text = 'Date :';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReportFaultForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end