classdef ReportsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MessageLabel             matlab.ui.control.Label
        ReportDisplayPanel       matlab.ui.container.Panel
        ReportDataTable          matlab.ui.control.Table
        ReportAxes               matlab.ui.control.UIAxes
        ReportOptionsPanel       matlab.ui.container.Panel
        GenerateReportButton     matlab.ui.control.Button
        LineSelectionDropDown    matlab.ui.control.DropDown
        SelectLineLabel          matlab.ui.control.Label
        ReportTypeDropDown       matlab.ui.control.DropDown
        ReportTypeDropDownLabel  matlab.ui.control.Label
        ReportsSectionLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        productionData table;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Define the expected column names based on your ProductionData.xlsx file
            % Ensure ProductionID is included as the first column
            expectedCols = {'ProductionID', 'LineID', 'LineName', 'Date', 'Shift', 'ProductType', 'QuantityProduced', 'EfficiencyPercentage'};

            try
                % Attempt to read the ProductionData.xlsx file
                tempProductionData = readtable('ProductionData.xlsx');

                % Check if all expected columns exist in the loaded table.
                if all(ismember(expectedCols, tempProductionData.Properties.VariableNames))
                    % If columns exist, reorder them to match expectedCols and assign
                    app.productionData = tempProductionData(:, expectedCols);
                    app.MessageLabel.Text = 'Production data loaded successfully for reporting.';
                    app.MessageLabel.FontColor = [0 0.5 0]; % Green
                else
                    % If some expected columns are missing or incorrectly named,
                    % initialize an empty table with the correct column structure.
                    app.MessageLabel.Text = 'Warning: ProductionData.xlsx missing or malformed columns. Cannot generate reports.';
                    app.MessageLabel.FontColor = [0.8 0.5 0]; % Orange
                    app.productionData = table(zeros(0,1), zeros(0,1), string.empty(0,1), datetime.empty(0,1), ...
                                               zeros(0,1), string.empty(0,1), zeros(0,1), zeros(0,1), ...
                                               'VariableNames', expectedCols);
                end

            catch ME
                % This catch block handles file not found or other readtable errors
                if (strcmp(ME.identifier, 'MATLAB:readtable:FileNotFound') || ...
                    strcmp(ME.identifier, 'MATLAB:table:EmptyTable') || ...
                    contains(ME.message, 'Variable names must be valid MATLAB identifiers'))

                    app.MessageLabel.Text = 'Production data file not found or is empty. Reports cannot be generated.';
                    app.MessageLabel.FontColor = [0.5 0.5 0.5]; % Gray
                else
                    % Catch any other unexpected errors during file loading
                    app.MessageLabel.Text = ['Error loading production data: ' ME.message];
                    app.MessageLabel.FontColor = [1 0 0]; % Red
                end
                % Ensure app.productionData is initialized with the correct structure even if error occurs
                app.productionData = table(zeros(0,1), zeros(0,1), string.empty(0,1), datetime.empty(0,1), ...
                                           zeros(0,1), string.empty(0,1), zeros(0,1), zeros(0,1), ...
                                           'VariableNames', expectedCols);
            end

            % Populate LineSelectionDropDown with unique line names from loaded data
            if ~isempty(app.productionData)
                uniqueLines = unique(app.productionData.LineName);
                % Prepend 'All Lines' to the list
                app.LineSelectionDropDown.Items = [{'All Lines'}; uniqueLines];
                app.LineSelectionDropDown.Value = 'All Lines'; % Set default selection
            else
                app.LineSelectionDropDown.Items = {'All Lines'}; % Only show 'All Lines' if no data
                app.LineSelectionDropDown.Value = 'All Lines';
            end

            % Clear initial report table and axes
            cla(app.ReportAxes); % Clear any default plot
            app.ReportDataTable.Data = [];
            app.ReportDataTable.ColumnName = {}; % Clear column names
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            % Clear previous plot and table data
            cla(app.ReportAxes); % Clear the axes
            app.ReportDataTable.Data = []; % Clear table data
            app.ReportDataTable.ColumnName = {}; % Clear table column names
            app.MessageLabel.Text = 'Generating report...';
            app.MessageLabel.FontColor = [0 0 0]; % Black

            % Get selected report type and line
            reportType = app.ReportTypeDropDown.Value;
            selectedLine = app.LineSelectionDropDown.Value;

            % Filter data based on selected line
            filteredData = app.productionData;
            if ~strcmp(selectedLine, 'All Lines')
                filteredData = filteredData(filteredData.LineName == selectedLine, :);
            end

            if isempty(filteredData)
                app.MessageLabel.Text = 'No data available for the selected filters.';
                app.MessageLabel.FontColor = [0.8 0.5 0]; % Orange
                return;
            end

            % --- Generate Report based on Report Type ---
            switch reportType
                case 'Total Production by Product'
                    % Group by ProductType and sum QuantityProduced
                    productSummary = groupsummary(filteredData, 'ProductType', 'sum', 'QuantityProduced');
                    
                    % Rename columns for clarity in display
                    productSummary.Properties.VariableNames{'sum_QuantityProduced'} = 'TotalQuantityProduced';
                    
                    % Sort for better visualization
                    productSummary = sortrows(productSummary, 'TotalQuantityProduced', 'descend');
                    
                    % Convert ProductType to categorical before plotting
                    productSummary.ProductType = categorical(productSummary.ProductType); % <-- این خط را اضافه کنید
                    
                    % Plotting on ReportAxes
                    bar(app.ReportAxes, productSummary.ProductType, productSummary.TotalQuantityProduced);
                    title(app.ReportAxes, 'Total Production by Product Type');
                    xlabel(app.ReportAxes, 'Product Type');
                    ylabel(app.ReportAxes, 'Total Quantity Produced');
                    grid(app.ReportAxes, 'on');
                    
                    % Display data in ReportDataTable
                    app.ReportDataTable.Data = productSummary;
                    app.ReportDataTable.ColumnName = productSummary.Properties.VariableNames;
                    
                    app.MessageLabel.Text = 'Report "Total Production by Product" generated successfully.';
                    app.MessageLabel.FontColor = [0 0.5 0]; % Green

                % Add other cases for different report types here later
                case 'Line Efficiency'
                    % Group by LineName and calculate the mean of EfficiencyPercentage
                    lineEfficiencySummary = groupsummary(filteredData, 'LineName', 'mean', 'EfficiencyPercentage');

                    % Rename columns for clarity
                    lineEfficiencySummary.Properties.VariableNames{'mean_EfficiencyPercentage'} = 'AverageEfficiency';

                    % Sort by average efficiency for better visualization
                    lineEfficiencySummary = sortrows(lineEfficiencySummary, 'AverageEfficiency', 'descend');

                    % Convert LineName to categorical for plotting
                    lineEfficiencySummary.LineName = categorical(lineEfficiencySummary.LineName);

                    % Plotting on ReportAxes
                    bar(app.ReportAxes, lineEfficiencySummary.LineName, lineEfficiencySummary.AverageEfficiency);
                    title(app.ReportAxes, 'Average Line Efficiency');
                    xlabel(app.ReportAxes, 'Production Line');
                    ylabel(app.ReportAxes, 'Average Efficiency (%)');
                    grid(app.ReportAxes, 'on');
                    ylim(app.ReportAxes, [0 100]); % Efficiency is usually 0-100%

                    % Display data in ReportDataTable
                    app.ReportDataTable.Data = lineEfficiencySummary;
                    app.ReportDataTable.ColumnName = lineEfficiencySummary.Properties.VariableNames;

                    app.MessageLabel.Text = 'Report "Line Efficiency" generated successfully.';
                    app.MessageLabel.FontColor = [0 0.5 0]; % Green
                case 'Production Trend (Weekly)'
                    % Ensure 'Date' column is datetime type
                    filteredData.Date = datetime(filteredData.Date); 
                    if ~isdatetime(filteredData.Date)
                        app.MessageLabel.Text = 'Error: Date column could not be converted to datetime format. Please check your Excel file.';
                        app.MessageLabel.FontColor = [1 0 0];
                        return;
                    end
                    
                    % Calculate the start of the week (Monday) manually
                    % Get the day of the week (1=Sunday, 2=Monday, ..., 7=Saturday)
                    dayNum = weekday(filteredData.Date); 
                    % Calculate days to subtract to get to the previous/current Monday
                    % For Monday (dayNum=2), subtract 0. For Sunday (dayNum=1), subtract 6 to get previous Monday.
                    daysToSubtract = mod(dayNum - 2 + 7, 7); 
                    filteredData.WeekStart = filteredData.Date - days(daysToSubtract);
                    
                    % Group by WeekStart and sum QuantityProduced
                    weeklyTrend = groupsummary(filteredData, 'WeekStart', 'sum', 'QuantityProduced');
                    
                    % Rename columns for clarity
                    weeklyTrend.Properties.VariableNames{'sum_QuantityProduced'} = 'TotalQuantityProduced';
                    
                    % Sort by WeekStart for correct trend display
                    weeklyTrend = sortrows(weeklyTrend, 'WeekStart');
                    
                    % Plotting on ReportAxes
                    plot(app.ReportAxes, weeklyTrend.WeekStart, weeklyTrend.TotalQuantityProduced, '-o'); % Line plot with markers
                    title(app.ReportAxes, ['Weekly Production Trend - ' selectedLine]);
                    xlabel(app.ReportAxes, 'Week Start Date');
                    ylabel(app.ReportAxes, 'Total Quantity Produced');
                    grid(app.ReportAxes, 'on');
                    
                    % Customize x-axis ticks for dates
                    xtickformat(app.ReportAxes, 'dd-MMM-yyyy');
                    
                    % Display data in ReportDataTable
                    weeklyTrend.WeekLabel = string(datestr(weeklyTrend.WeekStart, 'yyyy-mm-dd')); 
                    app.ReportDataTable.Data = weeklyTrend(:, {'WeekLabel', 'TotalQuantityProduced'});
                    app.ReportDataTable.ColumnName = {'Week', 'Total Quantity Produced'};
                    
                    app.MessageLabel.Text = 'Report "Production Trend (Weekly)" generated successfully.';
                    app.MessageLabel.FontColor = [0 0.5 0]; % Green
                case 'Production Trend (Monthly)'
                    % Ensure 'Date' column is datetime type
                    filteredData.Date = datetime(filteredData.Date); 
                    if ~isdatetime(filteredData.Date)
                        app.MessageLabel.Text = 'Error: Date column could not be converted to datetime format. Please check your Excel file.';
                        app.MessageLabel.FontColor = [1 0 0];
                        return;
                    end

                    % Calculate the start of the month for each production date
                    filteredData.MonthStart = dateshift(filteredData.Date, 'start', 'month');

                    % Group by MonthStart and sum QuantityProduced
                    monthlyTrend = groupsummary(filteredData, 'MonthStart', 'sum', 'QuantityProduced');

                    % Rename columns for clarity
                    monthlyTrend.Properties.VariableNames{'sum_QuantityProduced'} = 'TotalQuantityProduced';

                    % Sort by MonthStart for correct trend display
                    monthlyTrend = sortrows(monthlyTrend, 'MonthStart');

                    % Plotting on ReportAxes
                    plot(app.ReportAxes, monthlyTrend.MonthStart, monthlyTrend.TotalQuantityProduced, '-o'); % Line plot with markers
                    title(app.ReportAxes, ['Monthly Production Trend - ' selectedLine]);
                    xlabel(app.ReportAxes, 'Month Start Date');
                    ylabel(app.ReportAxes, 'Total Quantity Produced');
                    grid(app.ReportAxes, 'on');

                    % Customize x-axis ticks for dates (e.g., Year-Month)
                    xtickformat(app.ReportAxes, 'MMM-yyyy');

                    % Display data in ReportDataTable
                    % Create a readable MonthLabel column for the table
                    monthlyTrend.MonthLabel = string(datestr(monthlyTrend.MonthStart, 'yyyy-mm')); 
                    app.ReportDataTable.Data = monthlyTrend(:, {'MonthLabel', 'TotalQuantityProduced'});
                    app.ReportDataTable.ColumnName = {'Month', 'Total Quantity Produced'};

                    app.MessageLabel.Text = 'Report "Production Trend (Monthly)" generated successfully.';
                    app.MessageLabel.FontColor = [0 0.5 0]; % Green
                otherwise
                    app.MessageLabel.Text = 'Unknown report type selected.';
                    app.MessageLabel.FontColor = [1 0 0]; % Red
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReportsSectionLabel = uilabel(app.UIFigure);
            app.ReportsSectionLabel.Text = 'Reports Section';
            app.ReportOptionsPanel = uipanel(app.UIFigure);
            app.ReportOptionsPanel.Title = 'Report Options Panel';
            app.ReportTypeDropDownLabel = uilabel(app.ReportOptionsPanel);
            app.ReportTypeDropDownLabel.Text = 'Report Type DropDown';
            app.ReportTypeDropDown = uidropdown(app.ReportOptionsPanel);
            app.ReportTypeDropDown.Items = {'Total Production by Product', 'Line Efficiency', 'Production Trend (Weekly)', 'Production Trend (Monthly)'};
            app.ReportTypeDropDown.Value = 'Total Production by Product';
            app.SelectLineLabel = uilabel(app.ReportOptionsPanel);
            app.SelectLineLabel.Text = 'Select Line :';
            app.LineSelectionDropDown = uidropdown(app.ReportOptionsPanel);
            app.LineSelectionDropDown.Items = {'All Lines', 'Refrigerator Assembly', 'Plastic Parts Production', 'Washing Machine Assembly', 'Refrigerator Quality Control', 'Washing Machine Quality Control', 'Electronic Parts Production', 'Packaging Line', 'Logistics and Warehousing'};
            app.LineSelectionDropDown.Value = 'All Lines';
            app.GenerateReportButton = uibutton(app.ReportOptionsPanel, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.ReportDisplayPanel = uipanel(app.UIFigure);
            app.ReportDisplayPanel.Title = 'Report Output';
            app.ReportAxes = uiaxes(app.ReportDisplayPanel);
            title(app.ReportAxes, 'Title')
            xlabel(app.ReportAxes, 'X')
            ylabel(app.ReportAxes, 'Y')
            zlabel(app.ReportAxes, 'Z')
            app.ReportDataTable = uitable(app.ReportDisplayPanel);
            app.ReportDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ReportDataTable.RowName = {};
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Messages :';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReportsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end