classdef UserManagementApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ShowColumnsButton           matlab.ui.control.Button
        MessageLabel                matlab.ui.control.Label
        UsersTable                  matlab.ui.control.Table
        DeleteUserButton            matlab.ui.control.Button
        UpdateUserButton            matlab.ui.control.Button
        AddUserButton               matlab.ui.control.Button
        UserDetailsPanel            matlab.ui.container.Panel
        NameField                   matlab.ui.control.EditField
        NameLabel                   matlab.ui.control.Label
        RoleDropDown                matlab.ui.control.DropDown
        PasswordField               matlab.ui.control.EditField
        UsernameField               matlab.ui.control.EditField
        RoleLabel                   matlab.ui.control.Label
        PasswordLabel               matlab.ui.control.Label
        UsernameLabel               matlab.ui.control.Label
        UserManagementSectionLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        userData table;
    end
    
    methods (Access = private)
        
        function startupFcn(app)
           % Define the expected column names for initializing an empty table
            expectedCols = {'UserID', 'Username', 'Role', 'Password', 'Name'};

            app.userData = table(zeros(0,1), string.empty(0,1), string.empty(0,1), ...
                                 string.empty(0,1), string.empty(0,1), ...
                                 'VariableNames', expectedCols);
            
            try
                % Attempt to read the Users.xlsx file
                tempUserData = readtable('Users.xlsx');
        
                % Check if all expected columns exist in the loaded table from the file.
                if all(ismember(expectedCols, tempUserData.Properties.VariableNames))
                    app.userData = tempUserData(:, expectedCols);
                    app.MessageLabel.Text = 'Users loaded successfully.';
                    app.MessageLabel.FontColor = [0 0.5 0]; % Green
                else
                    app.MessageLabel.Text = 'Warning: Users.xlsx missing or malformed columns. Initializing empty user data.';
                    app.MessageLabel.FontColor = [0.8 0.5 0]; % Orange
                end
                
            catch ME
                if (strcmp(ME.identifier, 'MATLAB:readtable:FileNotFound') || ...
                    strcmp(ME.identifier, 'MATLAB:table:EmptyTable') || ...
                    strcmp(ME.identifier, 'MATLAB:readtable:FileFormatError'))
                    
                    app.MessageLabel.Text = 'No users file found or file is empty. A new one will be created upon saving.';
                    app.MessageLabel.FontColor = [0.5 0.5 0.5]; % Gray
                else
                    app.MessageLabel.Text = ['Error loading users: ' ME.message];
                    app.MessageLabel.FontColor = [1 0 0]; % Red
                end
            end
            
            app.UsersTable.Data = app.userData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddUserButton
        function AddUserButtonPushed(app, event)
            newUsername = app.UsernameField.Value;
            newPassword = app.PasswordField.Value;
            newRole = app.RoleDropDown.Value;
            newName = app.NameField.Value;
        
            % Generate a new UserID
            if isempty(app.userData) || all(isnan(app.userData.UserID))
                newUserID = 1;
            else
                currentMaxID = max(double(app.userData.UserID));
                newUserID = currentMaxID + 1;
            end
        
            % Basic validation
            if isempty(newUsername) || isempty(newPassword) || isempty(newName)
                app.MessageLabel.Text = 'Username, Password, and Name cannot be empty.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                return;
            end
        
            % Check if Username already exists
            if any(strcmp(app.userData.Username, newUsername))
                app.MessageLabel.Text = 'Username already exists. Please choose a different Username.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
                return;
            end
        
            % Create a new row ensuring the order matches your Excel file: UserID, Username, Role, Password, Name
            newRow = {newUserID, newUsername, newRole, newPassword, newName}; 
        
            % Add new row to app.userData table
            app.userData = [app.userData; newRow];
        
            % Update the display table
            app.UsersTable.Data = app.userData;
        
            try
                % Write the updated table back to the Excel file
                writetable(app.userData, 'Users.xlsx', 'WriteMode', 'overwrite'); % <-- اینجا تغییر کرد
                app.MessageLabel.Text = ['User ' newUsername ' added successfully.'];
                app.MessageLabel.FontColor = [0 0.5 0]; % Green
        
                % Clear input fields after successful addition
                app.UsernameField.Value = '';
                app.PasswordField.Value = '';
                app.RoleDropDown.Value = 'Operator'; % Reset to default
                app.NameField.Value = '';
            catch ME
                app.MessageLabel.Text = ['Error adding user: ' ME.message];
                app.MessageLabel.FontColor = [1 0 0]; % Red
            end
        end

        % Button pushed function: ShowColumnsButton
        function ShowColumnsButtonPushed(app, event)
             if ~isempty(app.userData) && istable(app.userData)
                disp('Column Names in app.userData:');
                disp(app.userData.Properties.VariableNames);
                app.MessageLabel.Text = 'Column names displayed in Command Window.';
                app.MessageLabel.FontColor = [0 0.5 0]; % Green
            else
                app.MessageLabel.Text = 'No user data loaded to display column names.';
                app.MessageLabel.FontColor = [1 0 0]; % Red
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UserManagementSectionLabel = uilabel(app.UIFigure);
            app.UserManagementSectionLabel.Text = 'User Management Section';
            app.UserDetailsPanel = uipanel(app.UIFigure);
            app.UserDetailsPanel.Title = 'User Details';
            app.UsernameLabel = uilabel(app.UserDetailsPanel);
            app.UsernameLabel.Text = 'Username:';
            app.PasswordLabel = uilabel(app.UserDetailsPanel);
            app.PasswordLabel.Text = 'Password:';
            app.RoleLabel = uilabel(app.UserDetailsPanel);
            app.RoleLabel.Text = 'Role:';
            app.UsernameField = uieditfield(app.UserDetailsPanel, 'text');
            app.PasswordField = uieditfield(app.UserDetailsPanel, 'text');
            app.RoleDropDown = uidropdown(app.UserDetailsPanel);
            app.RoleDropDown.Items = {'Admin', 'Operator', ''};
            app.RoleDropDown.Value = 'Operator';
            app.NameLabel = uilabel(app.UserDetailsPanel);
            app.NameLabel.Text = 'Name :';
            app.NameField = uieditfield(app.UserDetailsPanel, 'text');
            app.AddUserButton = uibutton(app.UIFigure, 'push');
            app.AddUserButton.ButtonPushedFcn = createCallbackFcn(app, @AddUserButtonPushed, true);
            app.AddUserButton.Text = 'Add User';
            app.UpdateUserButton = uibutton(app.UIFigure, 'push');
            app.UpdateUserButton.Text = 'Update User';
            app.DeleteUserButton = uibutton(app.UIFigure, 'push');
            app.DeleteUserButton.Text = 'Delete User';
            app.UsersTable = uitable(app.UIFigure);
            app.UsersTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UsersTable.RowName = {};
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Messages :';
            app.ShowColumnsButton = uibutton(app.UIFigure, 'push');
            app.ShowColumnsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowColumnsButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserManagementApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end