classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        MessageLabel   matlab.ui.control.Label
        LoginButton    matlab.ui.control.Button
        PasswordField  matlab.ui.control.EditField
        UsernameField  matlab.ui.control.EditField
        paswordLabel   matlab.ui.control.Label
        usernameLabel  matlab.ui.control.Label
        factorymonitoringsystemloginLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            % Clear previous messages
            app.MessageLabel.Text = ''; 
            app.MessageLabel.FontColor = [0 0 0]; % Reset to black
        
            inputUsername = app.UsernameField.Value;
            inputPassword = app.PasswordField.Value;
        
            % --- Load User Data ---
            % Make sure Users.xlsx is in the same directory as your app, or provide full path
            try
                userData = readtable('Users.xlsx');
            catch ME
                app.MessageLabel.Text = 'Error loading user data.';
                app.MessageLabel.FontColor = [1 0 0]; % Red color for error
                disp(['Error: ' ME.message]);
                return;
            end
        
            % --- Authentication Logic (Improved with strtrim) ---
            loggedInUserRow = [];
            for i = 1:height(userData)
                % Trim spaces from both stored data and input data for robust comparison
                storedUsername = strtrim(userData.Username{i});
                storedPassword = strtrim(userData.Password{i});
                
                if strcmp(storedUsername, strtrim(inputUsername)) && strcmp(storedPassword, strtrim(inputPassword))
                    loggedInUserRow = i;
                    break; % Found the user, exit loop
                end
            end
        
            if ~isempty(loggedInUserRow)
                userRole = strtrim(userData.Role{loggedInUserRow}); % Get role, trim spaces
                app.MessageLabel.Text = ['Login successful! Welcome, ' inputUsername];
                app.MessageLabel.FontColor = [0 0.5 0]; % Green color for success
                
                % --- Open next appropriate UI based on role ---
                if strcmp(userRole, 'admin')
                    app.MessageLabel.Text = 'Login successful! Redirecting to Admin Dashboard...';
                    % Here, you will open the Admin Dashboard GUI
                    % For now, let's just create a new figure as a placeholder
                    f_admin = uifigure('Name', 'Admin Dashboard');
                    uilabel(f_admin, 'Text', ['Welcome Admin: ' inputUsername], 'Position', [50 150 200 22]);
                    uilabel(f_admin, 'Text', 'This is your admin dashboard content.', 'Position', [50 100 250 22]);
                    
                    % Close the login window if desired
                    delete(app.UIFigure); % app.UIFigure refers to the login window
                    
                elseif strcmp(userRole, 'operator')
                    app.MessageLabel.Text = 'Login successful! Redirecting to Operator Dashboard...';
                    % Here, you will open the Operator Dashboard GUI
                    % For now, let's just create a new figure as a placeholder
                    f_operator = uifigure('Name', 'Operator Dashboard');
                    uilabel(f_operator, 'Text', ['Welcome Operator: ' inputUsername], 'Position', [50 150 200 22]);
                    uilabel(f_operator, 'Text', 'This is your operator dashboard content.', 'Position', [50 100 250 22]);
                    
                    % Close the login window if desired
                    delete(app.UIFigure);
                end
            else
                app.MessageLabel.Text = 'Login failed. Invalid username or password.';
                app.MessageLabel.FontColor = [1 0 0]; % Red color for error
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.factorymonitoringsystemloginLabel = uilabel(app.UIFigure);
            app.factorymonitoringsystemloginLabel.Interpreter = 'tex';
            app.factorymonitoringsystemloginLabel.Text = 'factory monitoring system login';
            app.usernameLabel = uilabel(app.UIFigure);
            app.usernameLabel.Text = 'username : ';
            app.paswordLabel = uilabel(app.UIFigure);
            app.paswordLabel.Text = 'pasword : ';
            app.UsernameField = uieditfield(app.UIFigure, 'text');
            app.PasswordField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end